classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ImageTransformationAppTab     matlab.ui.container.Tab
        OKButton                      matlab.ui.control.Button
        SelectImageTargetButton       matlab.ui.control.Button
        SelectInputImageLabel_2       matlab.ui.control.Label
        rSpinner                      matlab.ui.control.Spinner
        rSpinnerLabel                 matlab.ui.control.Label
        cSpinner                      matlab.ui.control.Spinner
        cSpinnerLabel                 matlab.ui.control.Label
        BrightnessSlider              matlab.ui.control.Slider
        BrightnessSliderLabel         matlab.ui.control.Label
        SelectTransformationDropDown  matlab.ui.control.DropDown
        SelectTransformationDropDownLabel  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        ImageTargetLabel              matlab.ui.control.Label
        ImageInputLabel_3             matlab.ui.control.Label
        ImageOutputLabel              matlab.ui.control.Label
        BlueHistogramTarget           matlab.ui.control.UIAxes
        GreenHistogramTarget          matlab.ui.control.UIAxes
        RedHistogramTarget            matlab.ui.control.UIAxes
        ImageTarget                   matlab.ui.control.UIAxes
        BlueHistogramInput            matlab.ui.control.UIAxes
        GreenHistogramInput           matlab.ui.control.UIAxes
        RedHistogramInput             matlab.ui.control.UIAxes
        ImageInput                    matlab.ui.control.UIAxes
        BlueHistogramOutput           matlab.ui.control.UIAxes
        GreenHistogramOutput          matlab.ui.control.UIAxes
        RedHistogramOutput            matlab.ui.control.UIAxes
        ImageOutput                   matlab.ui.control.UIAxes
        SelectInputImageLabel         matlab.ui.control.Label
        SelectImageInputButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        ImageSelected 
        ImageFileName
        Histogram
        AlgorithmSelected
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageInputButton
        function SelectImageInputButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                app.ImageFileName = fullfile(path, file);
                
                img = imread(app.ImageFileName);
                
                imshow(img,'Parent',app.ImageInput);
                
                hist_app(app);
    
            end
        end

        % Value changed function: SelectTransformationDropDown
        function SelectTransformationDropDownValueChanged(app, event)
            value = app.SelectTransformationDropDown.Value;
            app.AlgorithmSelected = value;
            set(app.RedHistogramOutput, 'Visible', 'off');
            set(app.GreenHistogramOutput, 'Visible', 'off');
            set(app.BlueHistogramOutput, 'Visible', 'off');
            set(app.RedHistogramTarget, 'Visible', 'off');
            set(app.GreenHistogramTarget, 'Visible', 'off');
            set(app.BlueHistogramTarget, 'Visible', 'off');
            switch value
                case 'Histogram Matching'
                    set(app.RedHistogramOutput, 'Visible', 'on');
                    set(app.GreenHistogramOutput, 'Visible', 'on');
                    set(app.BlueHistogramOutput, 'Visible', 'on');
                    set(app.RedHistogramTarget, 'Visible', 'on');
                    set(app.GreenHistogramTarget, 'Visible', 'on');
                    set(app.BlueHistogramTarget, 'Visible', 'on');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTransformationAppTab = uitab(app.TabGroup);
            app.ImageTransformationAppTab.Title = 'Image Transformation App';
            app.SelectImageInputButton = uibutton(app.ImageTransformationAppTab, 'push');
            app.SelectImageInputButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageInputButtonPushed, true);
            app.SelectImageInputButton.Text = 'Select Image';
            app.SelectInputImageLabel = uilabel(app.ImageTransformationAppTab);
            app.SelectInputImageLabel.Text = 'Select Input Image';
            app.Panel = uipanel(app.ImageTransformationAppTab);
            app.ImageOutput = uiaxes(app.Panel);
            app.ImageOutput.XTick = [];
            app.ImageOutput.YTick = [];
            app.RedHistogramOutput = uiaxes(app.Panel);
            app.GreenHistogramOutput = uiaxes(app.Panel);
            app.BlueHistogramOutput = uiaxes(app.Panel);
            app.ImageInput = uiaxes(app.Panel);
            app.ImageInput.XTick = [];
            app.ImageInput.YTick = [];
            app.RedHistogramInput = uiaxes(app.Panel);
            app.GreenHistogramInput = uiaxes(app.Panel);
            app.BlueHistogramInput = uiaxes(app.Panel);
            app.ImageTarget = uiaxes(app.Panel);
            app.ImageTarget.XTick = [];
            app.ImageTarget.YTick = [];
            app.RedHistogramTarget = uiaxes(app.Panel);
            app.GreenHistogramTarget = uiaxes(app.Panel);
            app.BlueHistogramTarget = uiaxes(app.Panel);
            app.ImageOutputLabel = uilabel(app.Panel);
            app.ImageOutputLabel.Text = 'Image Output';
            app.ImageInputLabel_3 = uilabel(app.Panel);
            app.ImageInputLabel_3.Text = 'Image Input';
            app.ImageTargetLabel = uilabel(app.Panel);
            app.ImageTargetLabel.Text = 'Image Target';
            app.SelectTransformationDropDownLabel = uilabel(app.ImageTransformationAppTab);
            app.SelectTransformationDropDownLabel.Text = 'Select Transformation';
            app.SelectTransformationDropDown = uidropdown(app.ImageTransformationAppTab);
            app.SelectTransformationDropDown.Items = {'Histogram', 'Brightening', 'Negative', 'Log Transformation', 'Exponential Transformation', 'Contrast Stretching', 'Histogram Equalization', 'Histogram Matching'};
            app.SelectTransformationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTransformationDropDownValueChanged, true);
            app.SelectTransformationDropDown.Value = 'Histogram';
            app.BrightnessSliderLabel = uilabel(app.ImageTransformationAppTab);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.ImageTransformationAppTab);
            app.BrightnessSlider.Limits = [0 255];
            app.BrightnessSlider.MajorTicks = [0 63 127 191 255];
            app.cSpinnerLabel = uilabel(app.ImageTransformationAppTab);
            app.cSpinnerLabel.Text = 'c';
            app.cSpinner = uispinner(app.ImageTransformationAppTab);
            app.rSpinnerLabel = uilabel(app.ImageTransformationAppTab);
            app.rSpinnerLabel.Text = 'r';
            app.rSpinner = uispinner(app.ImageTransformationAppTab);
            app.SelectInputImageLabel_2 = uilabel(app.ImageTransformationAppTab);
            app.SelectInputImageLabel_2.Text = 'Select Target Image';
            app.SelectImageTargetButton = uibutton(app.ImageTransformationAppTab, 'push');
            app.SelectImageTargetButton.Text = 'Select Image';
            app.OKButton = uibutton(app.ImageTransformationAppTab, 'push');
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end